classdef GuiTesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel_2              matlab.ui.container.Panel
        BalanceLabel_2       matlab.ui.control.Label
        BalanceLabel         matlab.ui.control.Label
        OutputTextArea       matlab.ui.control.TextArea
        OutputTextAreaLabel  matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        HitButton            matlab.ui.control.Button
        StandButton          matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
    end

    
    properties (Access = private)
        BlackjackGameEngine % Description
        playerHand    % Player's hand
        dealerHand    % Dealer's hand
        betAmount     % Current bet amount
    end

    
    methods (Access = private)

        function startupFcn(GuiTesting)
            % Initialize the Blackjack game
            app.BlackjackGame = BlackjackTest();
            app.playerHand = [];
            app.dealerHand = [];
            app.betAmount = 0;
            app.updateBalanceDisplay();
            app.OutputTextArea.Value = {'Welcome to Blackjack! Enter your bet and hit or stand.'};
        end

        function HitButtonPushed(app, event)
    if isempty(app.playerHand)  % Check if game started
        app.startGame();
    end

    [app.playerHand, app.BlackjackGame] = app.BlackjackGame.dealCard(app.playerHand);
    total = app.BlackjackGame.calculateTotal(app.playerHand);
    app.OutputTextArea.Value{end + 1} = sprintf('You drew: %s | Total: %d', ...
        app.BlackjackGame.handToString(app.playerHand), total);
    
    if total > 21
        app.OutputTextArea.Value{end + 1} = 'Bust! You lose.';
        app.endGame();
    end
end

function StandButtonPushed(app, event)
    if ~isempty(app.playerHand)
        app.BlackjackGame.dealerTurn(app.playerHand, app.dealerHand, app.betAmount);
        app.OutputTextArea.Value{end + 1} = 'Dealer finished. Check results.';
        app.endGame();
    end
end
        
        function startGame(app)
            % Start a new game
            app.betAmount = app.BetEditField.Value;
            app.playerHand = [];  % Reset player hand
            app.dealerHand = [];  % Reset dealer hand
            [app.playerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);
            [app.dealerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);
            app.updateBalanceDisplay();
            app.OutputTextArea.Value{end + 1} = sprintf('Bet Amount: $%d', app.betAmount);
            app.OutputTextArea.Value{end + 1} = sprintf('Your cards: %s', app.BlackjackGame.handToString(app.playerHand));
            app.OutputTextArea.Value{end + 1} = sprintf('Dealer shows: %s', app.BlackjackGame.cardNames{app.dealerHand(1)});
        end
        
        function endGame(app)
            % End the current game session
            app.playerHand = [];
            app.dealerHand = [];
            app.BetEditField.Value = 0;  % Reset bet field
        end
        
        function updateBalanceDisplay(app)
            % Update the balance display
            app.BalanceLabel.Text = sprintf('Balance: $%d', app.BlackjackGame.balance);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitButton
        function HitButtonPushedCallback(app, event)
            app.HitButton.ButtonPushedFcn = @(src, event) HitButtonPushed(app, event);

        end

        % Button pushed function: StandButton
        function StandButtonPushedCallback(app, event)
            app.StandButton.ButtonPushedFcn = @(src, event) StandButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Panel';
            app.StandButton = uibutton(app.Panel_2, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushedCallback, true);
            app.StandButton.Text = 'Stand';
            app.HitButton = uibutton(app.Panel_2, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushedCallback, true);
            app.HitButton.Text = 'Hit';
            app.EditFieldLabel = uilabel(app.Panel_2);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.OutputTextAreaLabel = uilabel(app.Panel_2);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.Panel_2);
            app.BalanceLabel = uilabel(app.Panel_2);
            app.BalanceLabel.Text = 'Balance';
            app.BalanceLabel_2 = uilabel(app.Panel_2);
            app.BalanceLabel_2.Text = 'Balance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end