classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        BalanceTextBox           matlab.ui.control.NumericEditField
        GameWindowTextbox        matlab.ui.control.TextArea
        GameWindowTextAreaLabel  matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        DealerHandTextbox        matlab.ui.control.TextArea
        DealerTextAreaLabel      matlab.ui.control.Label
        PlayerHandTextbox        matlab.ui.control.TextArea
        PlayerTextAreaLabel      matlab.ui.control.Label
        BalanceLabel             matlab.ui.control.Label
        BetAmountTextbox         matlab.ui.control.NumericEditField
        PlaceBetButton           matlab.ui.control.Button
        StandButton              matlab.ui.control.Button
        HitButton                matlab.ui.control.Button
    end


    properties (Access = public)
        Game % Instance of BlackjackTest class
        robots % Constructor for controller class
    end
    methods (Access = public)
        % Function to start or reset the game
        function startGame(app)
            app.Game = BlackjackTest();  % Initialize the Blackjack game
            app.robots = Controller();   % Initialize the Controller
            app.BetAmountTextbox.Value = 0;  % Reset bet amount field to 0
            app.updateGameWindow();  % Ensure UI reflects the new game state
        end

        % Callback for Place Bet button
        function placeBet(app)
            betAmount = app.BetAmountTextbox.Value;  % Get the bet amount
            currentBalance = app.BalanceTextBox.Value;  % Get current balance
            if betAmount <= 0 || betAmount > currentBalance
                % uialert(app.UIFigure, 'Invalid bet amount!', 'Bet Error');
                return;
            end
            app.Game.betAmount = betAmount;
            app.Game.balance = currentBalance - betAmount;  % Deduct the bet from the balance

            % Deal initial cards
            [app.Game.playerHand, app.Game] = app.Game.hitCard('player', 2);  % Deal 2 cards to player
            [app.Game.dealerHand, app.Game] = app.Game.hitCard('dealer', 2);  % Deal 2 cards to dealer

            % Update the UI to reflect the new game state
            app.updateGameWindow();
            app.checkBust();  % Check if the player has busted after dealing
        end

        % Callback for Hit button
        function hit(app)
            % Deal one card to the player
            [app.Game.playerHand, app.Game] = app.Game.hitCard('player', 1);
            app.updateGameWindow();  % Update the UI after hitting

            % Check if the player has busted
            if app.Game.calculateTotal(app.Game.playerHand) > 21
                app.updateGameWindow('Bust! Dealer Wins!');
            end
        end

        % Callback for Stand button

        function stand(app)
            % Trigger the dealer's turn in the BlackjackTest class
            app.Game = app.Game.onDealerTurn();

            % Update the app's UI after the dealer's turn
            app.updateGameWindow();

            % Get the totals to determine the winner
            playerTotal = app.Game.calculateTotal(app.Game.playerHand);
            dealerTotal = app.Game.calculateTotal(app.Game.dealerHand);

            % Display the winner message
            app.declareOutcome(app.Game.gameWindow);
        end

        % Function to update UI elements
        function updateGameWindow(app)
            app.BalanceTextBox.Value = app.Game.balance;  % Update balance

            % Update player's hand and total
            playerHandStr = ['Your cards: ', app.Game.handToString(app.Game.playerHand), ...
                ' | Total: ', num2str(app.Game.calculateTotal(app.Game.playerHand))];
            app.PlayerHandTextbox.Value = playerHandStr;

            % Update dealer's hand and total
            dealerHandStr = ['Dealer''s cards: ', app.Game.handToString(app.Game.dealerHand), ...
                ' | Total: ', num2str(app.Game.calculateTotal(app.Game.dealerHand))];
            app.DealerHandTextbox.Value = dealerHandStr;

            % Update game window messages
            app.GameWindowTextbox.Value = app.Game.gameWindow;
        end

        % Function to declare outcome and reset game
        function declareOutcome(app, message)
            % uialert(app.UIFigure, message, 'Game Over');
            % app.startGame(); % Reset game for a new round
        end

        function checkBust(app)
            if app.Game.calculateTotal(app.Game.playerHand) > 21
                app.declareOutcome('Bust! Dealer Wins!');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            app.hit();  % Call hit method directly
            app.robots.HitMe(app.robots);
            app.robots.DealCard(app.robots);
        end

        % Button pushed function: PlaceBetButton
        function PlaceBetButtonPushed(app, event)
            try
                app.placeBet();  % Attempt to place bet
                app.robots.PlayerBet(app.robots);
                app.robots.PickUpChipDealer(app.robots);
                
            catch ME
                % uialert(app.UIFigure, ME.message, 'Error in placing bet');
                disp(ME.stack);  % Display stack trace for debugging
            end
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            app.stand();
            % Update any relevant UI elements, for example:
            app.GameWindowTextbox.Value = app.Game.gameWindow;  % Display game messages
            app.robots.Stand(app.robots);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Game.gameStarted = true;  % Set game state to started
            app.startGame();  % Initialize and start the game
            app.robots.DealCard(app.robots);
            

        end

        % Value changed function: PlayerHandTextbox
        function PlayerHandTextboxValueChanged(app, event)
            app.Game = app.PlayerHandTextbox.Value;
        end

        % Value changed function: DealerHandTextbox
        function DealerHandTextboxValueChanged(app, event)
            app.Game = app.DealerHandTextbox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.HitButton = uibutton(app.Panel, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.Panel, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.PlaceBetButton = uibutton(app.Panel, 'push');
            app.PlaceBetButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceBetButtonPushed, true);
            app.PlaceBetButton.Text = 'Place Bet';
            app.BetAmountTextbox = uieditfield(app.Panel, 'numeric');
            app.BetAmountTextbox.AllowEmpty = 'on';
            app.BetAmountTextbox.Placeholder = '0';
            app.BetAmountTextbox.Value = [];
            app.BalanceLabel = uilabel(app.Panel);
            app.BalanceLabel.Text = 'Balance: ';
            app.PlayerTextAreaLabel = uilabel(app.Panel);
            app.PlayerTextAreaLabel.Text = 'Player';
            app.PlayerHandTextbox = uitextarea(app.Panel);
            app.PlayerHandTextbox.ValueChangedFcn = createCallbackFcn(app, @PlayerHandTextboxValueChanged, true);
            app.DealerTextAreaLabel = uilabel(app.Panel);
            app.DealerTextAreaLabel.Text = 'Dealer';
            app.DealerHandTextbox = uitextarea(app.Panel);
            app.DealerHandTextbox.ValueChangedFcn = createCallbackFcn(app, @DealerHandTextboxValueChanged, true);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GameWindowTextAreaLabel = uilabel(app.Panel);
            app.GameWindowTextAreaLabel.Text = 'Game Window';
            app.GameWindowTextbox = uitextarea(app.Panel);
            app.BalanceTextBox = uieditfield(app.Panel, 'numeric');
            app.BalanceTextBox.Value = 500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end